classdef SistemaEstacionamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SISTEMADEESTACIONAMIENTOLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ManualButton                   matlab.ui.control.Button
        ComenzarButton                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            max = 5;
            pila=[]; %Arreglo para la pila del estacionamiento
            % pila = zeros(1,5) % pila de ceros de dimension 1x5.
            cola=[]; %Arreglo para la cola de la cola de espera
            %Arreglo que suma las veces que un auto se mueve en la pila para permitir la salida de otro
            sacadoCola=[]; %Arreglo que suma las veces que un auto se mueve en la cola para permitir la salida de otro
            tope = 0, topeCola = 0; %Contadores
            dato=0; %Variable de tipo entero
            txtpantalla="";%texto para la validacion
            salida="", dat=""; %Variables de tipo caracter
            
            % MENU CONTEXTUAL
            %fig = uifigure;
            msg = 'Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?';
            titulo = 'MENÚ';
            selec = uiconfirm(app.UIFigure,msg,titulo, ...
                'Options',{'Ingresar Vehículo','Mostrar Vehículos Ingresados','Salida de Vehículos'}, ...
                'DefaultOption',1);
            %
                    
             
%             opts.Interpreter = 'tex';
%             % Include the desired Default answer
%             opts.Default = '';
%             % Use the TeX interpreter to format the question
%             cadena = 'Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?';
%             quest = cadena;
%             opcion1 = 'Ingresar Vehiculo';
%             opcion2 = 'Mostrar Vehiculos Ingresados';
%             opcion3 = 'Salida de Vehiculo'
% 
%             opc = {opcion1,opcion2,opcion3};
%            
%             answer = questdlg(quest,'Menu:',opcion1,opcion2,opcion3,opts);
           
            if strcmp(selec,'Ingresar Vehículo')
                disp('OK')
            elseif strcmp(selec,'Mostrar Vehículos Ingresados')
                disp('OK 2')
            elseif strcmp(selec,'Salida de Vehículos')
                disp('OK 3')
            else
                clc
            end
            
            function pilas(dat, pila, dato,max, tope)
                sacadoPila=[];
                for cont=0:length(pila)
                    if pila(cont) == dato
                        pila(cont) = 0;
                        tope=tope-1;
                        for b=cont:8
                            sacadoPila(b + 1) = sacadoPila(b + 1) + 1;
                        end
                        %JOptionPane.showMessageDialog(null, "El auto con placa Nº" + dat + " ha sido retirado."
                        %+ "\nEl número de veces movido para permitir la salida de otros fue: "
                        %+ sacadoPila(cont), "Aviso", JOptionPane.INFORMATION_MESSAGE);
                    end
                end
            end


            function colas(dat, cola, dato, max, topeCola)
                sacadCola=[];
                for cont = 0: length(cola)
                    if cola(cont) == dato
                        cola(cont) = 0;
                        topeCola=topeCola-1;
                        for b=cont:8
                            sacadCola(b + 1) = sacadCola(b + 1) + 1;
                        end
                        %                         JOptionPane.showMessageDialog(null, "El auto con placa Nº" + dat + " ha sido retirado."
                        %                         + "\nEl número de veces movido para permitir la salida de otros fue: "
                        %                         + sacadoCola[cont], "Aviso", JOptionPane.INFORMATION_MESSAGE);
                    end
                end
            end


        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
        app.TextArea.Value{1} = 'Manual de Ayuda';
        app.TextArea.Value{2} = sprintf('\n%s\n','Para comenzar a estacionar o sacar autos presione el botón Comenzar');
        %         "- Para documentarse sobre el uso del programa presione el botón Manual\n"+... 
%         "- Para Salir del programa completo puede hacer click en la X roja\n\n" + "Iniciando:\n\n"+...
%         + "- Al presionar el botón Comenzar  se ejecutara el cuerpo del programa donde\n"+...
%         " usted, luego de aceptar, introducira la opción de su preferecia\n"+...
%         " ya sea  opcion1 para 'Ingresar Vehiculo'; opcion2 para 'Mostrar Vehiculos'; opcion3 para 'Salida de Vehiculo' y opcion4 para 'Salir' respectivamente\n"
        %+ "- Si da click en la opcion 1 el programa le pedira el número de la placa del auto que desea introducir\n"
        %+"- Si da click en la opcion 2 el programa le pedira el número de la placa del auto que desea retirar.\n"
        %+ "- Si da click en la opcion 3 el programa le mostrara el estado del estacionamiento en tiempo real.\n";
        %+ "- Si da click en la opcion 4 el programa cerrara la ejecución del cuerpo del programa y podrá presionar\n"
        %+ " el mismo u otro botón si desea.\n"
        %+ "\n\nNota: Si elige la opción 4 sin haber terminado de introducir todos los dato\n, perdera los datos que haya introducido previamente.";


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'images.jpg';
            app.ComenzarButton = uibutton(app.UIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.SISTEMADEESTACIONAMIENTOLabel = uilabel(app.UIFigure);
            app.SISTEMADEESTACIONAMIENTOLabel.Text = 'SISTEMA DE ESTACIONAMIENTO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemaEstacionamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end